classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        NetlistMenu                 matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        netfileMenu                 matlab.ui.container.Menu
        xlsxfileMenu                matlab.ui.container.Menu
        ClearMenu                   matlab.ui.container.Menu
        ClearAllMenu                matlab.ui.container.Menu
        ClearTourchstonefilesMenu   matlab.ui.container.Menu
        ClearNetlistfilesMenu       matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout2                 matlab.ui.container.GridLayout
        TabGroup                    matlab.ui.container.TabGroup
        RectangularTab              matlab.ui.container.Tab
        GridLayout4                 matlab.ui.container.GridLayout
        GridLayout6                 matlab.ui.container.GridLayout
        RectPlotStyleDropDown       matlab.ui.control.DropDown
        PlotStyleDropDownLabel      matlab.ui.control.Label
        GridLayout5                 matlab.ui.container.GridLayout
        RectParameterPlotDropDown   matlab.ui.control.DropDown
        ParameterDropDown_2Label    matlab.ui.control.Label
        UIAxesP12                   matlab.ui.control.UIAxes
        UIAxesP21                   matlab.ui.control.UIAxes
        UIAxesP22                   matlab.ui.control.UIAxes
        UIAxesP11                   matlab.ui.control.UIAxes
        PolarTab                    matlab.ui.container.Tab
        GridLayout7                 matlab.ui.container.GridLayout
        GridLayout5_3               matlab.ui.container.GridLayout
        PolarParameterPlotDropDown  matlab.ui.control.DropDown
        ParameterDropDown_2Label_2  matlab.ui.control.Label
        SmithChartTab               matlab.ui.container.Tab
        GridLayout8                 matlab.ui.container.GridLayout
        P22SmithChartPanel          matlab.ui.container.Panel
        P21SmithChartPanel          matlab.ui.container.Panel
        P12SmithChartPanel          matlab.ui.container.Panel
        P11SmithChartPanel          matlab.ui.container.Panel
        GridLayout5_4               matlab.ui.container.GridLayout
        SmithParameterPlotDropDown  matlab.ui.control.DropDown
        ParameterDropDown_3Label    matlab.ui.control.Label
        CTCTab                      matlab.ui.container.Tab
        GridLayout9                 matlab.ui.container.GridLayout
        GridLayout10                matlab.ui.container.GridLayout
        TouchstoneListBox           matlab.ui.control.ListBox
        TouchstoneListBoxLabel      matlab.ui.control.Label
        Netlist2ListBox             matlab.ui.control.ListBox
        Netlist2ListBoxLabel        matlab.ui.control.Label
        Netlist1ListBox             matlab.ui.control.ListBox
        Netlist1ListBoxLabel        matlab.ui.control.Label
        SimulateButton              matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        GridLayout3                 matlab.ui.container.GridLayout
        ListBoxNetlistFiles         matlab.ui.control.ListBox
        NetlistFilesLabel           matlab.ui.control.Label
        ListBoxTouchstoneFiles      matlab.ui.control.ListBox
        TouchstoneFilesLabel        matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        ControlPanel                matlab.ui.container.Panel
        ZoEditFieldLabel_2          matlab.ui.control.Label
        ZoEditField                 matlab.ui.control.NumericEditField
        ZoEditFieldLabel            matlab.ui.control.Label
        PortnumberEditField         matlab.ui.control.NumericEditField
        PortnumberEditFieldLabel    matlab.ui.control.Label
        TouchtoneFileoptionsPanel   matlab.ui.container.Panel
        FormatDropDown              matlab.ui.control.DropDown
        FormatDropDownLabel         matlab.ui.control.Label
        ParameterDropDown           matlab.ui.control.DropDown
        ParameterDropDownLabel      matlab.ui.control.Label
        FreqUnitDropDown            matlab.ui.control.DropDown
        FreqUnitDropDownLabel       matlab.ui.control.Label
        OutputfileEditField         matlab.ui.control.EditField
        OutputfileEditFieldLabel    matlab.ui.control.Label
        InputfileEditField          matlab.ui.control.EditField
        InputfileEditFieldLabel     matlab.ui.control.Label
        SaveButton                  matlab.ui.control.Button
        OpenButton                  matlab.ui.control.Button
        RunButton                   matlab.ui.control.Button
        DropDownEndFreq             matlab.ui.control.DropDown
        DropDownStartFreq           matlab.ui.control.DropDown
        StepsEditField              matlab.ui.control.NumericEditField
        StepsEditFieldLabel         matlab.ui.control.Label
        EndFreqEditField            matlab.ui.control.NumericEditField
        EndFreqEditFieldLabel       matlab.ui.control.Label
        StartFreqEditField          matlab.ui.control.NumericEditField
        StartFreqEditFieldLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        net_fileName
        net_filePath
        net_file string {mustBeFile(net_file)} 
        xslx_fileName
        xslx_filePath
        xslx_file string {mustBeFile(xslx_file)} % Full path to the file
        Netlist_table 
        Netlist_CellArray
        ZMatrix
        YMatrix
        ABCD_Matrix
        SMatrix
        Start_Freq_Fld
        End_Freq_Fld
        Start_Freq
        End_Freq
        Start_Freq_Unit
        End_Freq_Unit
        Touchstone_FreqUnit 
        Frequencies
        Step
        Zo
        Z0
        Port_Num
        Run_Valid = false;
        Warning_Msg
        Parameters_Ready = false;
        Sim_Arg_Valid = false;
        Touchstone_Out_File
        Touchstone_Out_File_Name
        Touchstone_Out_File_Path
        Touchstone_Out_File_Format
        Touchstone_Input_File
        Touchstone_Input_File_Name
        Touchstone_Input_File_Path
        SMatrix_Touchstone
        ZMatrix_Touchstone
        YMatrix_Touchstone
        ABCD_Matrix_Touchstone
        AllRectAxes
        PolAxesP11
        PolAxesP12
        PolAxesP21
        PolAxesP22
        AllPolarAxes
        SmithAxesS11
        SmithAxesS12
        SmithAxesS21
        SmithAxesS22
        AllSmithAxes 
        RectParameterPlot
        PolarParameterPlot
        SmithParameterPlot
        S_Parameters_Obj
        Z_Parameters_Obj
        Y_Parameters_Obj
        ABCD_Parameters_Obj
        CTC_Netlist1 
        CTC_Netlist2
        CTC_Touchstone
        Sel_Touchstone
        RectPlotStyle
        Touchstone_WR_Parameter
        Touchstone_RD_Parameter
    end
    
    events (NotifyAccess = private)
        PathChanged 
        xlsxNetlistChanged
    end
    
    methods (Access = private)
        
        function Z_Matrix = Z_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num) 
            
            %calculate the number of nodes for the matrix size
            NetlistCASize = size(Netlist_CellArray);
            nodesC2 = Netlist_CellArray(:,2); %elements start nodes
            nodesC3 = Netlist_CellArray(:,3); %elements end nodes
            allNodesConn = [nodesC2; nodesC3];
            AllNodes = unique(allNodesConn,'sorted'); 
            Nodes = AllNodes(2:end); %Remove the reference node N0 (assumed it will always be named as that an therefore be at the top of the arrray)
            NodesNum = length(Nodes); 
            Nodes_Matrix = zeros(NodesNum,NodesNum); %Se crea una matriz de NxN donde N es la cantidad de puertos
            Y_Parameter_Matrix = zeros(Port_Num,Port_Num); %Temp variable, not exacly Y parameter matrix 
            Z_Parameter_Matrix = zeros(Port_Num,Port_Num);
            Z_Matrix = zeros(Port_Num,Port_Num, Step);
            Step_num = 0; 

            if NetlistCASize(1,1) == 1 %Netlist has a single element, therefore a series or shunt element or NodesNum == 2 (including gnd)
                %For file netlist.net ground = 0, while for netlist.xlsx ground = N0
                if (ismember('N0', AllNodes) || ismember('0', AllNodes))
                    for F = Start_Freq:(End_Freq-Start_Freq)/(Step-1):End_Freq
                        Step_num = Step_num + 1;
                        Bl=Netlist_CellArray{1,6};
                        opFreq=Netlist_CellArray{1,7};
                        Name=Netlist_CellArray{1,1};
                        Z_Matrix(:,:,Step_num) = (Calc_Impedance(app,Netlist_CellArray{1,4},Netlist_CellArray{1,5},F,Bl,opFreq,Name));
                    end
                else
                    Z_Matrix(:,:) = 0; %Series element doesnt have Z param
                end
           
            else  %Netlist has more than 1 row  

                for F = Start_Freq:(End_Freq-Start_Freq)/(Step-1):End_Freq
                    Step_num = Step_num + 1;
                    Nodes_Matrix = Nodes_Matrix_Fun(app,Netlist_CellArray,F); %Nodes_Matrix_fun function
    
                    for i=1 : Port_Num
                        RedRow_Nodes_Matrix = Nodes_Matrix;  %Esta igualacion se usa para guardar la matriz de Nodes_Matrix temporalmente y en la siguiente linea se 
                        RedRow_Nodes_Matrix(i,:) = [];          %elimina un renglon, esto es para crear las matrices reducidas 11, 12,  21, etc.
                    
                        for j = 1 : Port_Num
                            RedCol_Nodes_Matrix = RedRow_Nodes_Matrix;                  
                            RedCol_Nodes_Matrix(:,j) = [];      %lo mismo que en el caso anterior pero para eliminar columnas
                    
                            Y_Parameter_Matrix(i,j) = (det(Nodes_Matrix)/det(RedCol_Nodes_Matrix));  %Port admitance (since it is a nodes matriz), meaning Y parameter (escalar value)
                    
                             if j ~= i
                                if (rem(i,2) == 1 && rem(j,2) == 0) || (rem(i,2) == 0 && rem(j,2) == 1)  
                                    Z_Parameter_Matrix(i,j) =  -1/Y_Parameter_Matrix(i,j);            
                                 else 
                                     Z_Parameter_Matrix(i,j) =  1/Y_Parameter_Matrix(i,j);
                                 end
                             else %j==i
                                Z_Parameter_Matrix(i,j) =  1/Y_Parameter_Matrix(i,j);        %Conversion de Parameter Y a Z diagonal principal.
                             end
                        end
                    
                    end
            
                    Z_Matrix(:,:,Step_num) = Z_Parameter_Matrix;
                end
            end
        end
        
        function Nodes_Matrix = Nodes_Matrix_Fun(app, Netlist_CellArray, Freq) 
            Size_NodesCellArray = size(Netlist_CellArray);

            %calculate the number of nodes for the matrix size
            nodesC2 = Netlist_CellArray(:,2); %elements start nodes
            nodesC3 = Netlist_CellArray(:,3); %elements end nodes
            allNodesConn = [nodesC2; nodesC3];
            Nodes = unique(allNodesConn,'sorted'); 
            Nodes = Nodes(2:end); %Remove the reference node N0 (assumed it will always be named as that an therefore be at the top of the arrray)
            NodesNum = length(Nodes); 
            Nodes_Matrix = zeros(NodesNum,NodesNum); %Se crea una matriz de NxN donde N es la cantidad de puertos
            
            for i = 1 : NodesNum                                  %Iteracion en la cantidad de nodos para los renglones
                for j = 1 : NodesNum                              %Iteracion en la cantidad de nodos para las columas
                    for k = 1: Size_NodesCellArray(1,1)           %Iteracion para determinar los componentes en respectivo punto de la matriz nodal   
            
                        if strcmpi(Nodes(i),Nodes(j))             %Diagonal principal de la matriz de nodos
                            if strcmpi(Nodes(j),Netlist_CellArray(k,2)) || strcmpi(Nodes(j),Netlist_CellArray(k,3))    %siempre que el nodo en analisis este presente se sumara                
                                Bl=Netlist_CellArray{k,6};       % Para concentrados Bl=0 y no tiene efecto en Calc_Impedancia
                                opFreq=Netlist_CellArray{k,7};   % Para concentrados opFreq=0 y no tiene efecto en Calc_Impedancia
                                Name=Netlist_CellArray{k,1};
                                Nodes_Matrix(i,j) = Nodes_Matrix(i,j) + 1/(Calc_Impedance(app,Netlist_CellArray{k,4},Netlist_CellArray{k,5},Freq,Bl,opFreq,Name));
                            end
                        else  %diagonales los elementos que interactuan con el nodo principal y no principal
                            if (strcmpi(Nodes(j),Netlist_CellArray(k,2)) || strcmpi(Nodes(j),Netlist_CellArray(k,3))) && (strcmpi(Nodes(i),Netlist_CellArray(k,2)) || strcmpi(Nodes(i),Netlist_CellArray(k,3)))
                                Bl=Netlist_CellArray{k,6};       % Para concentrados Bl=0 y no tiene efecto en Calc_Impedancia
                                opFreq=Netlist_CellArray{k,7};   % Para concentrados opFreq=0 y no tiene efecto en Calc_Impedancia
                                Name=Netlist_CellArray{k,1};
                                Nodes_Matrix(i,j) = Nodes_Matrix(i,j) - 1/(Calc_Impedance(app,Netlist_CellArray{k,4},Netlist_CellArray{k,5},Freq,Bl,opFreq,Name));
                            end
                        end
            
                    end    
                end
            end
        end

        function Z = Calc_Impedance(app, Elem_Type, Value, Freq,Bl,opFreq,Name)
            
            if Name =="Series"
                Impedancia=0;
                componentes=strsplit(Elem_Type,',');
                for j=1:(size(componentes,2)-1)
                    Impedancia=Calc_Impedance(app,componentes{1,j},Value(j),Freq,Bl(j),opFreq(j),componentes{1,j})+Impedancia;
                end
                Z=Impedancia;
            end

            switch Elem_Type
                case 'R'
                    Z = Value;
                case 'L'
                    Z = 2*1i*pi*Value*Freq;
                case 'C'
                    Z = 1/(2*1i*pi*Freq*Value);
                case 'TL_Y1'
                    % Recordando que Y1=-Y21
                    Y=Y_Distribuidos(app,Freq,Value,Bl,opFreq,"TL");
                    Y1=-Y(2,1);
                    Z=1/Y1;
                case 'TL_Y2'
                    % Recordando que Y2=Y11+Y12
                    Y=Y_Distribuidos(app,Freq,Value,Bl,opFreq,"TL");
                    Y2=(Y(1,1)+Y(1,2));
                    Z=1/Y2;
                case 'TL_Y3'
                    % Recordando que Y3=Y22+Y12
                    Y=Y_Distribuidos(app,Freq,Value,Bl,opFreq,"TL");
                    Y3=(Y(2,2)+Y(1,2));
                    Z=1/Y3;
        
                case 'SSC'
                    Z=Y_Distribuidos(app,Freq,Value,Bl,opFreq,"SSC");
                    
                case 'SOC'
                    Z=Y_Distribuidos(app,Freq,Value,Bl,opFreq,"SOC");
                    
             end
        end

        function Y=Y_Distribuidos(app,f,Z,Bl,opFreq,tipo)
        
        
            % Constantes, obtener longitud [m] equivalente a la
            % longitud eléctrica en grados introducida por el usuario
            vp=3E8;
            oplambda=(vp/opFreq);
            opBeta=(2*pi)/(oplambda);
            l=Bl/opBeta;
    
    
            % Dependientes  de frecuencia
            lambda=(vp/f);
            Beta=(2*pi)/(lambda);
            
            % Segun el tipo de componente cambia el metodo para calcular Z
            switch tipo
                case "TL"
                    A=cos(Beta*l);
                    B=1i*Z*sin(Beta*l);
                    C=1i*sin(Beta*l)/Z;
                    D=cos(Beta*l);
    
                    ABCD=[A B; C D];
                    Y=abcd2y(ABCD);
                case "SSC"
                 
                    % Inductor = j*2*pi*f*L
                    Bl=deg2rad(Bl);
                    Y=1i*Z*tan(Bl*(f/opFreq));
                    
                case "SOC"
                    
                    Bl=deg2rad(Bl);
                    Y=-1i*Z*cot(Bl*(f/opFreq));
                     
             end
                
        end
        
        function Freq_Value = Calc_Freq_Value(app, Freq_Field_Value, Freq_Unit)
            switch Freq_Unit
                case 'Hz'
                    Freq_Value = Freq_Field_Value; 
                case 'KHz'
                    Freq_Value = Freq_Field_Value*10^3; 
                case 'MHz' 
                    Freq_Value = Freq_Field_Value*10^6; 
                case 'GHz'
                    Freq_Value = Freq_Field_Value*10^9; 
                case 'THz'
                    Freq_Value = Freq_Field_Value*10^12; 
            end
        end
        
        function Retrieve_Values_For_Run(app) %Function called everytime run boton is pressed
            app.Start_Freq_Fld = app.StartFreqEditField.Value;
            app.End_Freq_Fld = app.EndFreqEditField.Value;
            app.Start_Freq = Calc_Freq_Value(app, app.Start_Freq_Fld, app.DropDownStartFreq.Value); 
            app.End_Freq = Calc_Freq_Value(app, app.End_Freq_Fld, app.DropDownEndFreq.Value); 

            if app.Start_Freq > app.End_Freq 
                app.Sim_Arg_Valid = false;
                return
            end

            app.Step = app.StepsEditField.Value;
            app.Zo = app.ZoEditField.Value;
            app.Z0 = app.ZoEditField.Value; %internal variable to write touchstones with the value used during sim (not editable by user).
            app.Port_Num = app.PortnumberEditField.Value;
            app.Frequencies = linspace(app.Start_Freq, app.End_Freq, app.Step); %linspace(x1,x2,n) generates n points. The spacing between the points is (x2-x1)/(n-1).
            app.Touchstone_Out_File_Name = app.OutputfileEditField.Value;
            app.Touchstone_Out_File_Format = app.FormatDropDown.Value;
            app.Touchstone_FreqUnit = app.FreqUnitDropDown.Value;
            app.RectPlotStyle = app.RectPlotStyleDropDown.Value;
            app.Touchstone_WR_Parameter = app.ParameterDropDown.Value;
            app.RectParameterPlot = app.RectParameterPlotDropDown.Value;
            app.RectPlotStyle = app.RectPlotStyleDropDown.Value;
            app.PolarParameterPlot = app.PolarParameterPlotDropDown.Value;
            app.SmithParameterPlot = app.SmithParameterPlotDropDown.Value;
            app.Sim_Arg_Valid = true;
        end

        function Y_Matrix = Y_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num)
            
            %calculate the number of nodes for the matrix size
            NetlistCASize = size(Netlist_CellArray);
            nodesC2 = Netlist_CellArray(:,2); %elements start nodes
            nodesC3 = Netlist_CellArray(:,3); %elements end nodes
            allNodesConn = [nodesC2; nodesC3];
            AllNodes = unique(allNodesConn,'sorted'); 
            Step_num = 0; 
            Y_Matrix = zeros(Port_Num,Port_Num, Step);
    
            if NetlistCASize(1,1) == 1 %Netlist has only one element, either a series or shunt element
                %For file netlist.net ground = 0, while for netlist.xlsx ground = N0
                if (ismember('N0', AllNodes) || ismember('0', AllNodes))
                    for F = Start_Freq:(End_Freq-Start_Freq)/(Step-1):End_Freq
                        %Y_Matrix = zeros(2,2,Step_num);
                        Y_Matrix(:,:) = 0; %Shunt elements doesnt have Y param
                    end
                else
                    Y_Matrix = zeros(2,2, Step_num);
                    for F = Start_Freq:(End_Freq-Start_Freq)/(Step-1):End_Freq
                        Step_num = Step_num + 1;
                        Bl=Netlist_CellArray{1,6};
                        opFreq=Netlist_CellArray{1,7};
                        Name=Netlist_CellArray{1,1};
                        Y_Matrix(1,1,Step_num) = 1/(Calc_Impedance(app,Netlist_CellArray{1,4},Netlist_CellArray{1,5},F,Bl,opFreq,Name));
                        Y_Matrix(1,2,Step_num) = -1/(Calc_Impedance(app,Netlist_CellArray{1,4},Netlist_CellArray{1,5},F,Bl,opFreq,Name));
                        Y_Matrix(2,1,Step_num) = -1/(Calc_Impedance(app,Netlist_CellArray{1,4},Netlist_CellArray{1,5},F,Bl,opFreq,Name));
                        Y_Matrix(2,2,Step_num) = 1/(Calc_Impedance(app,Netlist_CellArray{1,4},Netlist_CellArray{1,5},F,Bl,opFreq,Name));
                    end
                end
            else %Netlist has more than one element 
                Z_Matrix = Z_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num);
                Y_Matrix = pageinv(Z_Matrix);
            end
        end
        
        function ABCD_Matrix = ABCD_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num)
            %calculate the number of nodes for the matrix size
            NetlistCASize = size(Netlist_CellArray);
            nodesC2 = Netlist_CellArray(:,2); %elements start nodes
            nodesC3 = Netlist_CellArray(:,3); %elements end nodes
            allNodesConn = [nodesC2; nodesC3];
            AllNodes = unique(allNodesConn,'sorted'); 
    
             if Port_Num == 2
                if NetlistCASize(1,1) == 1 %Netlist has only one element, either a series or shunt element
                    if (ismember('N0', AllNodes) || ismember('0', AllNodes)) %For file netlist.net ground = 0, while for netlist.xlsx ground = N0
                        Z_Matrix = Z_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num);
                        ABCD_Matrix = z2abcd(Z_Matrix);
                    else
                        Y_Matrix = Y_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num);
                        ABCD_Matrix = y2abcd(Y_Matrix);
                    end
                else 
                    Z_Matrix = Z_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num);
                    ABCD_Matrix = z2abcd(Z_Matrix); 
                end
             else
                 disp("ABCD parameters are only available for 2 port networks");
                 app.Warning_Msg = msgbox("ABCD parameters are only available for 2 port networks");
                 ABCD_Matrix = zeros(2,2);
             end
        end

        
        function Netlist_CellArray = Netlist2CellArray(app, netlist_file)

            % La función recibe el netlist de Spice, lo convierte a formato
            % de netlist de excel y saca el circuito equivalente 

            Touchstone_WR_File= fopen(netlist_file,'r','n','UTF-8');
            
            n=1;
            pat= lettersPattern;
            
            while(~feof(Touchstone_WR_File))
                nline = fgetl(Touchstone_WR_File);
                if(~contains(nline,'.end') && ~contains(nline,'*') && ~contains(nline,'.backanno')) 
                   splitted_nline = strsplit(nline);
                   NumVal= sip2num(splitted_nline{:,4});
                   
                   %Define the type of element from the component name.
                   %Note: Components must be named starting with the letter
                   %corresponding to the type of element their are, ej.
                   %Capacitor --> C1
                   elem_Type = splitted_nline{:,1};
                   elem_Type = cell2mat(extract(elem_Type,pat));

                   %For stubs and tranmision lines get Bl and frequency
                   if elem_Type=="SOC" || elem_Type=="SSC" || elem_Type=="TL" 
                       Bl= str2num(splitted_nline{:,5});
                       freq=str2num(splitted_nline{:,6});
                   else
                       Bl=0;
                       freq=0;
                   end
            
                   %Create custom cell array from netlist info that follows
                   %the excell format.
                   %Name, 1st Node, 2nd Node, type, Value
                    netlistCell(n,:) = [splitted_nline{:,1}, splitted_nline{:,2}, splitted_nline{:,3}, {elem_Type}, {NumVal}, Bl, freq];                    
                    n=n+1;
                end
            end

            % Inicialización de variable para guardar info
            X=1;
            X2=1;
        
            nueva_netlistCell= {};
            
                % Se recorre el netlist para encontrar distribuidos
            for i=1:size(netlistCell,1)

                nodoInicial=netlistCell{i,2};
                nodoFinal=netlistCell{i,3};
                type=cell2mat(netlistCell(i,4));
                value=cell2mat(netlistCell(i,5));
                Bl= cell2mat(netlistCell(i,6));
                Bl=deg2rad(Bl);
                opFreq=cell2mat(netlistCell(i,7));
                
                pat=digitsPattern;
                nodoFinalCero=cell2mat(extract(nodoFinal,pat));
                
        
                if type=="TL" && nodoFinalCero~="0" 
                    % Guarda informacion de las lineas de transmision
                    Info(X,:)={nodoInicial,nodoFinal,value,Bl,opFreq,type};
                    X=X+1;
                elseif type=="TL" && nodoFinalCero=="0" 
                    % Una linea de transmision a Tierra es un stub en corto circuito
                    newType= 'SSC'
                    nueva_netlistCell(X2,:)=[{newType},{nodoInicial},{nodoFinal},{newType},{value},netlistCell{i,6},{opFreq}];
                    X2=X2+1;
                else
                    % Asigna directamente los elementos concentrados a la nueva
                    % lista
                    nueva_netlistCell(X2,:)=netlistCell(i,:);
                    X2=X2+1;
                end
            
            end
            
            % Con la información obtenida, crea el nuevo netlist
            % Variable Info no existe cuando una netlist es completamente de concentrados
            if exist("Info","var")
                for j=1:size(Info,1)
                
                % Agregar circuito equivalente
                % Y1=Y21, Y2=Y11+Y12 y Y3=Y22+Y12
                tipo=Info{j,6};
                
                NombreY1=[tipo '_Y1'];
                NombreY2=[tipo '_Y2'];
                NombreY3=[tipo '_Y3'];
        
                Y1=[{NombreY1},{Info{j,1}},{Info{j,2}},{NombreY1},{Info{j,3}},{Info{j,4}},{Info{j,5}}];
                Y2=[{NombreY2},{Info{j,1}},{'0'},{NombreY2},{Info{j,3}},{Info{j,4}},{Info{j,5}}];
                Y3=[{NombreY3},{Info{j,2}},{'0'},{NombreY3},{Info{j,3}},{Info{j,4}},{Info{j,5}}];
                
                % Se guardan las nuevas impedancias al netlist
                last_column=size(nueva_netlistCell,1)+1;
                nueva_netlistCell(last_column:last_column+2,:)=[Y1;Y2;Y3];
        
                end
            end

            % Se busca si no hay nodo de Tierra, significa que estan en serie

            nodesC2 = nueva_netlistCell(:,2); %elements start nodes
            nodesC3 = nueva_netlistCell(:,3); %elements end nodes
            allNodesConn = [nodesC2; nodesC3];
            Nodes = unique(allNodesConn,'sorted'); 
            
            seriesType='';
            seriesValue=[];
            seriesBl=[];
            seriesopFreq=[];

            pat=digitsPattern;
            firstNode=cell2mat(extract(Nodes{1,1},pat));
        
        
            if firstNode ~="0"
                for j=1:size(nueva_netlistCell,1)
                   tipo2=nueva_netlistCell{j,4};
                   seriesType= [tipo2, ',',seriesType];
        
                   value2=nueva_netlistCell{j,5};
                   seriesValue= [value2, seriesValue];
        
                   Bl2=nueva_netlistCell{j,6};
                   seriesBl= [Bl2, seriesBl];
        
                   opFreq2=nueva_netlistCell{j,7};
                   seriesopFreq= [opFreq2, seriesopFreq];
        
                   nueva_netlistCell(1,:)=['Series','N001','N002',{seriesType},{seriesValue},{seriesBl},{seriesopFreq}];
                end

                nueva_netlistCell=nueva_netlistCell(1,:);
            end

        
            Netlist_CellArray=nueva_netlistCell;


        end   

        function nueva_netlistCell= NetlistCircuitoEquivalente(app,netlistCell)
               
            % Inicialización de variable para guardar info
            X=1;
            X2=1;
        
            nueva_netlistCell= {};
            
                % Se recorre el netlist para encontrar distribuidos
            for i=1:size(netlistCell,1)
        
                    
                    nodoInicial=netlistCell{i,2};
                    nodoFinal=netlistCell{i,3};
                    type=cell2mat(netlistCell(i,4));
                    value=cell2mat(netlistCell(i,5));
                    Bl= cell2mat(netlistCell(i,6));
                    Bl=deg2rad(Bl);
                    opFreq=cell2mat(netlistCell(i,7));

                    pat=digitsPattern;
                    nodoFinalCero=cell2mat(extract(nodoFinal,pat));
            
                    if type=="TL" && nodoFinalCero~="0" 
                        % Guarda informacion de las lineas de transmision
                        Info(X,:)={nodoInicial,nodoFinal,value,Bl,opFreq,type};
                        X=X+1;
                    elseif type=="TL" && nodoFinalCero=="0" 
                        % Una linea de transmision a Tierra es un stub en corto circuito
                        newType= 'SSC'
                        nueva_netlistCell(X2,:)=[{newType},{nodoInicial},{nodoFinal},{newType},{value},netlistCell{i,6},{opFreq}];
                        X2=X2+1;
                    else
                        % Asigna directamente los elementos concentrados a la nueva
                        % lista
                        nueva_netlistCell(X2,:)=netlistCell(i,:);
                        X2=X2+1;
                    end
            
            end
            
            % Con la información obtenida, crea el nuevo netlist
            % Variable Info no existe cuando una netlist es completamente de concentrados
            if exist("Info","var")
                for j=1:size(Info,1)
                
                % Agregar circuito equivalente
                % Y1=Y21, Y2=Y11+Y12 y Y3=Y22+Y12
                tipo=Info{j,6};
                
                NombreY1=[tipo '_Y1'];
                NombreY2=[tipo '_Y2'];
                NombreY3=[tipo '_Y3'];
        
                Y1=[{NombreY1},{Info{j,1}},{Info{j,2}},{NombreY1},{Info{j,3}},{Info{j,4}},{Info{j,5}}];
                Y2=[{NombreY2},{Info{j,1}},{'0'},{NombreY2},{Info{j,3}},{Info{j,4}},{Info{j,5}}];
                Y3=[{NombreY3},{Info{j,2}},{'0'},{NombreY3},{Info{j,3}},{Info{j,4}},{Info{j,5}}];
                
                % Se guardan las nuevas impedancias al netlist
                last_column=size(nueva_netlistCell,1)+1;
                nueva_netlistCell(last_column:last_column+2,:)=[Y1;Y2;Y3];
        
                end
            end
            
            % Se busca si no hay nodo de Tierra, significa que estan en serie
        
            nodesC2 = nueva_netlistCell(:,2); %elements start nodes
            nodesC3 = nueva_netlistCell(:,3); %elements end nodes
            allNodesConn = [nodesC2; nodesC3];
            Nodes = unique(allNodesConn,'sorted'); 
            
            seriesType='';
            seriesValue=[];
            seriesBl=[];
            seriesopFreq=[];

             pat=digitsPattern;
             firstNode=cell2mat(extract(Nodes{1,1},pat));
        
            if firstNode ~="0"
                for j=1:size(nueva_netlistCell,1)
                   tipo2=nueva_netlistCell{j,4};
                   seriesType= [tipo2, ',',seriesType];
        
                   value2=nueva_netlistCell{j,5};
                   seriesValue= [value2, seriesValue];
        
                   Bl2=nueva_netlistCell{j,6};
                   seriesBl= [Bl2, seriesBl];
        
                   opFreq2=nueva_netlistCell{j,7};
                   seriesopFreq= [opFreq2, seriesopFreq];
        
                   nueva_netlistCell(1,:)=['Series','N001','N002',{seriesType},{seriesValue},{seriesBl},{seriesopFreq}];
                end
        
                nueva_netlistCell=nueva_netlistCell(1,:);
            end


        end
        
        function Plot_Rect_Charts(app, parameter_obj, rect_plot_style) %Only plots P11 to P22 params (as a two port network)
            app.AllRectAxes = [app.UIAxesP11, app.UIAxesP12; app.UIAxesP21, app.UIAxesP22];

            switch class(parameter_obj) 
                case 'sparameters'
                    title_param = {'S11', 'S12'; 'S21', 'S22'};
                case 'zparameters'
                    title_param = {'Z11', 'Z12'; 'Z21', 'Z22'};
                case 'yparameters'
                    title_param = {'Y11', 'Y12'; 'Y21', 'Y22'};
                case 'abcdparameters'
                    title_param = {'A', 'B'; 'C', 'D'};
                otherwise
                    title_param = {'P11', 'P12'; 'P21', 'P22'};
            end

            switch class(parameter_obj) %parameter_obj type is either S, Z, Y or ABCD
                case 'sparameters'
                    switch rect_plot_style
                        case 'Magnitude'
                            for i = 1 : 2
                                for j = 1: 2
                                    axis = app.AllRectAxes(i,j);
                                    rfplot(axis,parameter_obj,i,j);
                                    axis.Title.String = title_param{i,j};
                                end
                            end
                         
                        case 'Phase'
                            for i = 1 : 2
                                for j = 1: 2
                                    axis = app.AllRectAxes(i,j);
                                    rfplot(axis,parameter_obj,i,j,'angle');
                                    axis.Title.String = title_param{i,j};
                                end
                            end

                        case 'Real'
                            for i = 1 : 2
                                for j = 1: 2
                                    axis = app.AllRectAxes(i,j);
                                    rfplot(axis,parameter_obj,i,j,'real');
                                    axis.Title.String = title_param{i,j};
                                end
                            end

                        case 'Imaginary'
                            for i = 1 : 2
                                for j = 1: 2
                                    axis = app.AllRectAxes(i,j);
                                    rfplot(axis,parameter_obj,i,j,'imag');
                                    axis.Title.String = title_param{i,j};
                                end
                            end
                    end %S rect_plot_style switch

                otherwise %Any other parameter are plotted as below
                    switch rect_plot_style
                        case 'Magnitude'
                            for i = 1 : 2
                                for j = 1: 2
                                    axis = app.AllRectAxes(i,j);
                                    param = rfparam(parameter_obj,i,j); 
                                    plot(axis, parameter_obj.Frequencies, mag2db(abs(param)),"");
                                    axis.Title.String = title_param{i,j};
                                    ylabel(axis,"Magnitude (dB)")
                                end
                            end
                                                        
                        case 'Phase'
                            for i = 1 : 2
                                for j = 1: 2
                                    axis = app.AllRectAxes(i,j);
                                    param = rfparam(parameter_obj,i,j); 
                                    plot(axis, parameter_obj.Frequencies, rad2deg(angle(param)));
                                    axis.Title.String = title_param{i,j};
                                    ylabel(axis,"Phase (degrees)")
                                end
                            end

                        case 'Real'
                            for i = 1 : 2
                                for j = 1: 2
                                    axis = app.AllRectAxes(i,j);
                                    param = rfparam(parameter_obj,i,j); 
                                    plot(axis, parameter_obj.Frequencies, real(param));
                                    axis.Title.String = title_param{i,j};
                                    ylabel(axis,"Real")
                                end
                            end

                        case 'Imaginary'
                            for i = 1 : 2
                                for j = 1: 2
                                    axis = app.AllRectAxes(i,j);
                                    param = rfparam(parameter_obj,i,j); 
                                    plot(axis, parameter_obj.Frequencies, imag(param));
                                    axis.Title.String = title_param{i,j};
                                    ylabel(axis,"Imaginary")
                                end
                            end
                    end %Z rect_plot_style switch
            end %class parameter switch
        end

        
        function Plot_Polar_Charts(app, parameter_obj) %Only plots P11 to P22 params, parameter_obj type is either S, Z, Y or ABCD
            app.AllPolarAxes = [app.PolAxesP11, app.PolAxesP12; app.PolAxesP21, app.PolAxesP22];
            switch class(parameter_obj) 
                case 'sparameters'
                    title_param = {'S11', 'S12'; 'S21', 'S22'};
                case 'zparameters'
                    title_param = {'Z11', 'Z12'; 'Z21', 'Z22'};
                case 'yparameters'
                    title_param = {'Y11', 'Y12'; 'Y21', 'Y22'};
                case 'abcdparameters'
                    title_param = {'A', 'B'; 'C', 'D'};
                otherwise
                    title_param = {'P11', 'P12'; 'P21', 'P22'};
            end

            for i = 1 : 2
                for j = 1: 2
                    axis = app.AllPolarAxes(i,j);
                    param = rfparam(parameter_obj,i,j);
                    polarplot(axis,param); %polarplot(2+3i,'*')
                    axis.Title.String = title_param{i,j};
                end
            end
        end

        
        function Plot_Smith_Charts(app, parameter_obj) %parameter_obj type is either S, Z, Y or ABCD
            app.AllSmithAxes = [app.P11SmithChartPanel, app.P12SmithChartPanel; app.P21SmithChartPanel, app.P22SmithChartPanel];
            for i = 1 : 2
                for j = 1: 2
                    axis = app.AllSmithAxes(i,j);
                    smithplot(parameter_obj,i,j,Parent=axis); %smithplot('Parent', uipanel) smithplot(s,1,1, 'GridType','ZY');
                    %parameter = rfparam(parameter_obj,i,j);
                    %smithplot(parameter,Parent=axis); 
                end
            end
        end
        
        function S_Matrix = S_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num, Z0)
            %calculate the number of nodes for the matrix size
            NetlistCASize = size(Netlist_CellArray);
            nodesC2 = Netlist_CellArray(:,2); %elements start nodes
            nodesC3 = Netlist_CellArray(:,3); %elements end nodes
            allNodesConn = [nodesC2; nodesC3];
            AllNodes = unique(allNodesConn,'sorted'); 
    
            if NetlistCASize(1,1) == 1
                if (ismember('N0', AllNodes) || ismember('0', AllNodes)) %For file netlist.net ground = 0, while for netlist.xlsx ground = N0
                    Z_Matrix = Z_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num);
                    S_Matrix = z2s(Z_Matrix,Z0);
                else
                    Y_Matrix = Y_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num);
                    S_Matrix = y2s(Y_Matrix,Z0);
                end
            else 
                Z_Matrix = Z_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num);
                S_Matrix = z2s(Z_Matrix,Z0);
            end
        end
        
        function [CTC_Parameters_Struct, frequencies, Z0, port_num] = Circuit_Touchstone_Connection(app, First_Netlist_CellArray, Touchstone_S_Obj, Second_Netlist_CellArray)
            
            %obj info created from touchstone
            port_num = 2;
            frequencies = Touchstone_S_Obj.Frequencies;
            step = length(frequencies);
            start_freq = frequencies(1,1);
            end_freq = frequencies(step,1);
            Z0 = Touchstone_S_Obj.Impedance;
            S_Matrix_Touchstone = Touchstone_S_Obj.Parameters; 

            if ~strcmp(First_Netlist_CellArray,'None')
                ABCD_Matrix_1st_Netlist = ABCD_parameters(app, First_Netlist_CellArray, start_freq, end_freq, step, port_num);
            else 
                ABCD_Matrix_1st_Netlist =1;
            end

            if ~strcmp(Second_Netlist_CellArray,'None')
                ABCD_Matrix_2st_Netlist = ABCD_parameters(app, Second_Netlist_CellArray, start_freq, end_freq, step, port_num);
            else 
                ABCD_Matrix_2st_Netlist = 1;
            end 

            ABCD_Matrix_Tch = s2abcd(S_Matrix_Touchstone,Z0);
            ABCD_1stNet_Tch = pagemtimes(ABCD_Matrix_1st_Netlist,ABCD_Matrix_Tch);
            %ABCD_1stNet_Tch_2ndNet = pagemtimes(ABCD_1stNet_Tch,ABCD_Matrix_2st_Netlist);
            %S_Matrix_CTC_Network = abcd2s(ABCD_1stNet_Tch_2ndNet,Z0);

            %Convert abcd to all parameters (2 port network)
            CTC_ABCD_Matrix = pagemtimes(ABCD_1stNet_Tch,ABCD_Matrix_2st_Netlist);
            CTC_SMatrix = abcd2s(CTC_ABCD_Matrix,app.Zo);
            CTC_ZMatrix = abcd2z(CTC_ABCD_Matrix);
            CTC_YMatrix = abcd2y(CTC_ABCD_Matrix);

            %Create parameter objets for easy manipulation at main program
            ABCD_Parameter_CTC_Network = abcdparameters(CTC_ABCD_Matrix,frequencies);
            S_Parameter_CTC_Network = sparameters(CTC_SMatrix,frequencies,Z0);
            Z_Parameter_CTC_Network = zparameters(CTC_ZMatrix,frequencies);
            Y_Parameter_CTC_Network = yparameters(CTC_YMatrix,frequencies);
            CTC_Parameters_Struct = struct('S',S_Parameter_CTC_Network,'Z',Z_Parameter_CTC_Network,'Y',Y_Parameter_CTC_Network,'ABCD',ABCD_Parameter_CTC_Network);
        end
        
        function Reset_Values_Fun(app)
            app.StartFreqEditField.Value = 1;
            app.EndFreqEditField.Value = 1;
            app.DropDownStartFreq.Value = 'Hz';
            app.DropDownEndFreq.Value = 'Hz';
            app.StepsEditField.Value = 1;
            app.ZoEditField.Value = 50;
            app.PortnumberEditField.Value = 2;
            app.OutputfileEditField.Value = 'parameters';
            app.InputfileEditField.Value = '';
            app.FormatDropDown.Value = 'RI';
            app.FreqUnitDropDown.Value = 'Hz';
            app.ListBoxNetlistFiles.Items = {};  
            app.ListBoxNetlistFiles.ItemsData = {}; 
            app.ListBoxTouchstoneFiles.Items = {};
            app.ListBoxTouchstoneFiles.ItemsData = {};
            app.Netlist1ListBox.Items = {'None'};
            app.Netlist1ListBox.ItemsData = {'None'};
            app.Netlist2ListBox.Items = {'None'};
            app.Netlist2ListBox.ItemsData = {'None'};
            app.TouchstoneListBox.Items = {};
            app.TouchstoneListBox.ItemsData = {};            
            app.Parameters_Ready = false;
            app.Run_Valid = false;
            %clear netlist table
            varTypes = ["string","string","string", "string","string", "string","string"];
            varNames = {'Name', '1st Node', '2nd Node', 'Type', 'Value','Bl','Operating Frequency'};
            app.Netlist_table = table('Size',[1 7],'VariableTypes',varTypes,'VariableNames',varNames); %[row col]
            app.Netlist_table{:,:} = zeros(1,7);
            app.UITable.Data = app.Netlist_table;
            app.ParameterDropDown.Value = 'S';
            app.RectParameterPlotDropDown.Value = 'S';
            app.PolarParameterPlotDropDown.Value = 'S';
            app.SmithParameterPlotDropDown.Value = 'S';
            app.RectPlotStyleDropDown.Value = 'Magnitude';
            app.S_Parameters_Obj = 0;
            app.Z_Parameters_Obj = 0;
            app.Y_Parameters_Obj = 0;
            app.ABCD_Parameters_Obj = 0;
        end
        
        function Write_Touchstone(app, Parameter_Matrix, Frequencies, Touchstone_Out_File, FrequencyUnit, Z0, Format, Touchstone_WR_Parameter)
            %Note: Frequencies must be an array of a single column (n,1) as defined for sparameters objects, otherwise line 555 will fail. 
            sz_Parameter_Matrix = size(Parameter_Matrix);
            %Touchstone_Out_File = append(Touchstone_Out_File,'.', Touchstone_WR_Parameter,num2str(sz_Parameter_Matrix(1,1)),'p');
            Touchstone_WR_File = fopen(Touchstone_Out_File,'wt+');
            
            switch FrequencyUnit
                case 'Hz'
                    div_factor = 1;
                case 'KHz'
                    div_factor = 1e3;
                case 'MHz'
                    div_factor = 1e6;
                case 'GHz'
                    div_factor = 1e9;
            end
            
            switch Format
                case 'RI'
                    fprintf(Touchstone_WR_File,'%s %1s %1s %1s %1s %1s\n','#',FrequencyUnit,Touchstone_WR_Parameter,Format,'R',num2str(Z0));
                    fprintf(Touchstone_WR_File,'\n');
                    a = real(Parameter_Matrix);
                    b = imag(Parameter_Matrix);
            
                case 'MA'
                    fprintf(Touchstone_WR_File,'%s %1s %1s %1s %1s %1s\n','#',FrequencyUnit,Touchstone_WR_Parameter,Format,'R',num2str(Z0));
                    fprintf(Touchstone_WR_File,'\n');
                    a = abs(Parameter_Matrix);
                    b = rad2deg(angle(Parameter_Matrix));
            
                case 'DB'
                    fprintf(Touchstone_WR_File,'%s %1s %1s %1s %1s %1s\n','#',FrequencyUnit,Touchstone_WR_Parameter,Format,'R',num2str(Z0));
                    fprintf(Touchstone_WR_File,'\n');
                    a = mag2db(abs(Parameter_Matrix));        
                    b = rad2deg(angle(Parameter_Matrix));
            end
            
            for i = 1 : sz_Parameter_Matrix(1,3)             %Itera en la cantidad de frencuencias
                fprintf(Touchstone_WR_File,'%1.15e ',(Frequencies(i,1)/(div_factor)));
                for j = 1 : sz_Parameter_Matrix(1,1)         %Itera en los renglones de la matriz
                    for k = 1 : sz_Parameter_Matrix(1,2)     %Itera en las columnas de la matriz
        
                        if ((k == sz_Parameter_Matrix(1,2)) && (j ~= sz_Parameter_Matrix(1,1)))               
                            fprintf(Touchstone_WR_File,' % 1.16e % 1.16e ', a(j,k,i), b(j,k,i));
                        elseif ((k == sz_Parameter_Matrix(1,2)) && (j == sz_Parameter_Matrix(1,1)))
                            fprintf(Touchstone_WR_File,' % 1.16e % 1.16e', a(j,k,i), b(j,k,i));
                        else
                            fprintf(Touchstone_WR_File,'% 1.16e % 1.16e', a(j,k,i), b(j,k,i));
                        end
        
                    end
                end
                fprintf(Touchstone_WR_File,'\n');
            end
            
            fclose(Touchstone_WR_File);
        end
        

        function [RD_Matrix, Frequencies, Z0, Touchstone_Parameter] = Read_Touchstones(app, Touchstone_Input_File)
            fileID = fopen(Touchstone_Input_File,'r');

            Datos_parameters = textscan(fileID,'%f %f %f %f %f %f %f %f %f','CommentStyle','!','HeaderLines',2);

            frewind(fileID);
            
            while true
               Datos_Formato = fgets(fileID);
               Datos_Formato = split(Datos_Formato,' ');
                if strcmpi(Datos_Formato(1,1),'#')
                    Bandera = fgets(fileID);
                    Bandera = split(Bandera, ' ');
                    %Bandera = cell2mat(Bandera)
                    Flat = cell2mat(Bandera(1,1));
                    Flat = str2double(Flat);
                    %G = 0
                    if isnan(Flat)
                       Flat = 'no estan pegados';
                     %  G = 1;
                    end
            
                    if isnumeric(Flat)
                    %    G = 5;
                        frewind(fileID);
                        while true
                         salto = fgets(fileID);
                         salto = split(salto,' ');
                            if strcmpi(salto(1,1),'#')
                                break
                            end
                        end
                        Datos_parameters = textscan(fileID,'%f %f %f %f %f %f %f %f %f','CommentStyle','!','HeaderLines',0);
                    end
                    break
                end
            end
            
            
            X = 1;
            while true
                if strcmpi (Datos_Formato(X,1),'')
                    Datos_Formato(X,:) = [];
                    X = X -1;
                end
            
                sz2 = size(Datos_Formato);
                if sz2(1,1) == 6
                    break
                end
                
                X = X + 1;
            end
            
            Formato_datos = cell2mat(Datos_Formato(4,1));
            Formato_datos = lower(Formato_datos);
            Touchstone_Parameter = cell2mat(Datos_Formato(3,1));
            Unidad_Frecuencia = cell2mat(Datos_Formato(2,1));
            Unidad_Frecuencia = lower(Unidad_Frecuencia);
            %Puertos = 2;
            Z0 = cell2mat(Datos_Formato(6,1));
            Z0 = str2double(Z0);
            
            switch Unidad_Frecuencia
                case 'hz'
                    multiplicador = 1;
                case 'khz'
                    multiplicador = 1e3;
                case 'mhz'
                    multiplicador = 1e6;       
                case'ghz'
                    multiplicador = 1e9;
            end
            
            Frequencies = (Datos_parameters{1,1})*multiplicador;
            
            D_1 = Datos_parameters{1,2};
            D_2 = Datos_parameters{1,3};
            D_3 = Datos_parameters{1,4};
            D_4 = Datos_parameters{1,5};
            D_5 = Datos_parameters{1,6};
            D_6 = Datos_parameters{1,7};
            D_7 = Datos_parameters{1,8};
            D_8 = Datos_parameters{1,9};
            sz_2 = size(D_1);
            RD_Matrix = zeros(2,2,sz_2(1,1));
            
            switch Formato_datos
            
                case 'ma'        
                    for i = 1 : sz_2(1,1)
                        RD_Matrix(1,1,i) = D_1(i,1)*cos(deg2rad(D_2(i,1))) + D_1(i,1)*sin(deg2rad(D_2(i,1)))*1i;
                        RD_Matrix(1,2,i) = D_3(i,1)*cos(deg2rad(D_4(i,1))) + D_3(i,1)*sin(deg2rad(D_4(i,1)))*1i;
                        RD_Matrix(2,1,i) = D_5(i,1)*cos(deg2rad(D_6(i,1))) + D_5(i,1)*sin(deg2rad(D_6(i,1)))*1i;
                        RD_Matrix(2,2,i) = D_7(i,1)*cos(deg2rad(D_8(i,1))) + D_7(i,1)*sin(deg2rad(D_8(i,1)))*1i;
                    end
            
                case 'ri'     
                     for i = 1 : sz_2(1,1)
                        RD_Matrix(1,1,i) = D_1(i,1) + D_2(i,1)*1i;
                        RD_Matrix(1,2,i) = D_3(i,1) + D_4(i,1)*1i;
                        RD_Matrix(2,1,i) = D_5(i,1) + D_6(i,1)*1i;
                        RD_Matrix(2,2,i) = D_7(i,1) + D_8(i,1)*1i;
                     end
            
                case 'db'
                    for i = 1 : sz_2(1,1)
                        RD_Matrix(1,1,i) = db2mag(D_1(i,1))*cos(deg2rad(D_2(i,1))) + db2mag(D_1(i,1))*sin(deg2rad(D_2(i,1)))*1i;
                        RD_Matrix(1,2,i) = db2mag(D_3(i,1))*cos(deg2rad(D_4(i,1))) + db2mag(D_3(i,1))*sin(deg2rad(D_4(i,1)))*1i;
                        RD_Matrix(2,1,i) = db2mag(D_5(i,1))*cos(deg2rad(D_6(i,1))) + db2mag(D_5(i,1))*sin(deg2rad(D_6(i,1)))*1i;
                        RD_Matrix(2,2,i) = db2mag(D_7(i,1))*cos(deg2rad(D_8(i,1))) + db2mag(D_7(i,1))*sin(deg2rad(D_8(i,1)))*1i;
                    end
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PolAxesP11 = polaraxes('parent',app.GridLayout7);
            app.PolAxesP11.Title.String = 'S11';
            app.PolAxesP12 = polaraxes('parent',app.GridLayout7);
            app.PolAxesP12.Title.String = 'S12';
            app.PolAxesP21 = polaraxes('parent',app.GridLayout7);
            app.PolAxesP21.Title.String = 'S21';
            app.PolAxesP22 = polaraxes('parent',app.GridLayout7);
            app.PolAxesP22.Title.String = 'S22';
            %app.AllPolarAxes = [app.PolAxesP11, app.PolAxesP12; app.PolAxesP21, app.PolAxesP22];
        end

        % Menu selected function: xlsxfileMenu
        function xlsxfileMenuSelected(app, event)
            [app.xslx_fileName,app.xslx_filePath] = uigetfile('*.xlsx');
            if isequal(app.xslx_fileName,0)
                disp('User selected Cancel');
                app.Run_Valid = false;
            else
                disp(['User selected ', fullfile(app.xslx_filePath,app.xslx_fileName)]);
                app.xslx_file = fullfile(app.xslx_filePath,app.xslx_fileName);
                app.Netlist_table = readtable(app.xslx_file); 
                app.Netlist_table.Properties.VariableNames = {'Name', '1st Node', '2nd Node', 'Type', 'Value','Bl','Operating Frequency'}; % names of columns
                app.UITable.Data = app.Netlist_table;
                app.Netlist_table.Properties.VariableDescriptions{1} = 'Name'; 
                app.Netlist_table.Properties.VariableDescriptions{2} = '1st Node';
                app.Netlist_table.Properties.VariableDescriptions{3} = '2nd Node';
                app.Netlist_table.Properties.VariableDescriptions{4} = 'Type';
                app.Netlist_table.Properties.VariableDescriptions{5} = 'Value';
                app.Netlist_table.Properties.VariableDescriptions{6} = 'Bl';
                app.Netlist_table.Properties.VariableDescriptions{7} = 'Operating Frequency';
                app.UITable.ColumnName = app.Netlist_table.Properties.VariableDescriptions;
                % Aqui cambio
                app.Netlist_CellArray = NetlistCircuitoEquivalente(app,table2cell(app.Netlist_table));
                app.ListBoxNetlistFiles.Items = [app.ListBoxNetlistFiles.Items, {app.xslx_fileName}]; %horizontal concatenation
                app.ListBoxNetlistFiles.ItemsData = [app.ListBoxNetlistFiles.ItemsData, {app.Netlist_CellArray}];
                app.ListBoxNetlistFiles.Value = app.Netlist_CellArray;

                %1st netlist to CTC simulation
                app.Netlist1ListBox.Items = [app.Netlist1ListBox.Items, {app.xslx_fileName}];
                app.Netlist1ListBox.ItemsData = [app.Netlist1ListBox.ItemsData, {app.Netlist_CellArray}];
                %app.CTC_Netlist1 = app.Netlist1ListBox.Value;

                %2st netlist to CTC simulation
                app.Netlist2ListBox.Items = [app.Netlist2ListBox.Items, {app.xslx_fileName}];
                app.Netlist2ListBox.ItemsData = [app.Netlist2ListBox.ItemsData, {app.Netlist_CellArray}];
                %app.CTC_Netlist2 = app.Netlist2ListBox.Value;

                app.Run_Valid = true;
            end

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            Retrieve_Values_For_Run(app);
            if app.Run_Valid
                if app.Sim_Arg_Valid
                    app.Parameters_Ready = false;
                    progDlg = uiprogressdlg(app.UIFigure,'Title','Simulator info', 'Message','Start of simulation');
                    pause(.5)
    
                    % Perform calculations
                    progDlg.Value = .33; 
                    progDlg.Message = 'Loading data';
                    pause(.5)
    
                    % Perform calculations
                    progDlg.Value = .67;
                    progDlg.Message = 'Calculating circuit parameters';
                    pause(.5)
         
                        app.ZMatrix = Z_parameters(app, app.Netlist_CellArray, app.Start_Freq, app.End_Freq, app.Step, app.Port_Num); 
                        app.YMatrix = Y_parameters(app, app.Netlist_CellArray, app.Start_Freq, app.End_Freq, app.Step, app.Port_Num); 
                        app.ABCD_Matrix = ABCD_parameters(app, app.Netlist_CellArray, app.Start_Freq, app.End_Freq, app.Step, app.Port_Num);
                        app.SMatrix = S_parameters(app, app.Netlist_CellArray, app.Start_Freq, app.End_Freq, app.Step, app.Port_Num, app.Zo);
                                
                        %Create parameters objects from simulation results
                        app.S_Parameters_Obj = sparameters(app.SMatrix,app.Frequencies,app.Zo);
                        app.Z_Parameters_Obj = zparameters(app.ZMatrix,app.Frequencies);
                        app.Y_Parameters_Obj = yparameters(app.YMatrix,app.Frequencies);
                        app.ABCD_Parameters_Obj = abcdparameters(app.ABCD_Matrix,app.Frequencies);
    
                        app.Z0 = app.Zo; %Since Zo can be edited by user, Z0 keeps the value used during simulation
    
                        app.Parameters_Ready = true; %For touchstone storage
    
                        %Graph results
                        switch app.RectParameterPlot 
                            case 'S'
                                Plot_Rect_Charts(app, app.S_Parameters_Obj, app.RectPlotStyle);
                            case 'Z'
                                Plot_Rect_Charts(app, app.Z_Parameters_Obj, app.RectPlotStyle);
                            case 'Y'
                                Plot_Rect_Charts(app, app.Y_Parameters_Obj, app.RectPlotStyle);
                            case 'ABCD'
                                Plot_Rect_Charts(app, app.ABCD_Parameters_Obj, app.RectPlotStyle);
                        end 
    
                        switch app.PolarParameterPlot 
                            case 'S'
                                Plot_Polar_Charts(app, app.S_Parameters_Obj);
                            case 'Z'
                                Plot_Polar_Charts(app, app.Z_Parameters_Obj);
                            case 'Y'
                                Plot_Polar_Charts(app, app.Y_Parameters_Obj);
                            case 'ABCD'
                                Plot_Polar_Charts(app, app.ABCD_Parameters_Obj);
                        end 
    
                        switch app.SmithParameterPlot 
                            case 'S'
                                Plot_Smith_Charts(app, app.S_Parameters_Obj);
                            case 'Z'
                                Plot_Smith_Charts(app, app.Z_Parameters_Obj);
                            case 'Y'
                                Plot_Smith_Charts(app, app.Y_Parameters_Obj);
                            case 'ABCD'
                                Plot_Smith_Charts(app, app.ABCD_Parameters_Obj);
                        end 
    
     
                    % try
                    %
                    % here goes all parameters code
                    %
                    % catch ME % ME is of the class MException (in-built Matlab)
                    %     str = sprintf(['Parameters calculation & ploting problem \n' 'Matlab says: \n' ME.message]);
                    %     app.Warning_Msg = msgbox(str,'Error: Simulation fail');
                    %     disp(str)
                    %     app.Parameters_Ready = false;
                    % end
                    
    
                    % Finish calculations
                    progDlg.Value = 1;
                    progDlg.Message = 'Simulation complete';
                    pause(.8)
                
                    % Close dialog box
                    close(progDlg) 
                else
                    app.Warning_Msg = msgbox("Please introduce valid simulation parameters","Warning: Invalid simulation parameters");
                    app.Parameters_Ready = false;
                end
            else 
                app.Warning_Msg = msgbox("Please load a netlist before running a simulation","Warning: Missing netlist");
                app.Parameters_Ready = false;
            end
        end

        % Value changed function: StartFreqEditField
        function StartFreqEditFieldValueChanged(app, event)
            if app.StartFreqEditField.Value == 0
                app.StartFreqEditField.Value = 1;
                app.Start_Freq_Fld = app.StartFreqEditField.Value;
                app.Start_Freq = Calc_Freq_Value(app, app.Start_Freq_Fld, app.DropDownStartFreq.Value); 
                app.Warning_Msg = msgbox("Star frequency must be different than 0","Warning: Wrong star frequency value");
            else
                app.Start_Freq_Fld = app.StartFreqEditField.Value;
                app.Start_Freq = Calc_Freq_Value(app, app.Start_Freq_Fld, app.DropDownStartFreq.Value); 
            end
        end

        % Value changed function: EndFreqEditField
        function EndFreqEditFieldValueChanged(app, event)
            if app.EndFreqEditField.Value == 0
                app.EndFreqEditField.Value = 1;
                app.End_Freq_Fld = app.EndFreqEditField.Value;
                app.Warning_Msg = msgbox("End frequency must be different than 0","Warning: Wrong end frequency value");
            else
                app.End_Freq_Fld = app.EndFreqEditField.Value; 
                app.End_Freq = Calc_Freq_Value(app, app.End_Freq_Fld, app.DropDownEndFreq.Value);  
            end
        end

        % Value changed function: DropDownStartFreq
        function DropDownStartFreqValueChanged(app, event)
            app.Start_Freq = Calc_Freq_Value(app, app.StartFreqEditField.Value, app.DropDownStartFreq.Value);
            %app.Start_Freq = Calc_Freq_Value(app, app.Start_Freq_Fld, app.DropDownStartFreq.Value);
        end

        % Value changed function: DropDownEndFreq
        function DropDownEndFreqValueChanged(app, event)
            app.End_Freq = Calc_Freq_Value(app, app.End_Freq_Fld, app.DropDownEndFreq.Value);
            %app.End_Freq = Calc_Freq_Value(app, app.EndFreqEditField.Value, app.DropDownEndFreq.Value);
        end

        % Value changed function: StepsEditField
        function StepsEditFieldValueChanged(app, event)
            app.Step = app.StepsEditField.Value;
        end

        % Value changed function: PortnumberEditField
        function PortnumberEditFieldValueChanged(app, event)
            app.Port_Num = floor(app.PortnumberEditField.Value);
            app.PortnumberEditField.Value = app.Port_Num;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if app.Parameters_Ready
                %Retrieve required info from app
                app.Touchstone_Out_File_Name = app.OutputfileEditField.Value;
                app.Touchstone_Out_File_Format = app.FormatDropDown.Value;
                app.Touchstone_FreqUnit = app.FreqUnitDropDown.Value;
                app.Touchstone_WR_Parameter = app.ParameterDropDown.Value;
                app.Port_Num = app.PortnumberEditField.Value;

                if contains(app.Touchstone_Out_File_Name,'.s2p') || contains(app.Touchstone_Out_File_Name,'.z2p') || contains(app.Touchstone_Out_File_Name,'.y2p') || contains(app.Touchstone_Out_File_Name,'.abcd2p')
                    app.Touchstone_Out_File_Name = split(app.Touchstone_Out_File_Name,'.');
                    app.Touchstone_Out_File_Name = app.Touchstone_Out_File_Name{1};
                end
                               
                %app.Touchstone_Out_File_Name = append(app.Touchstone_Out_File_Name,'.',lower(app.Touchstone_WR_Parameter),num2str(sz_Parameter_Matrix(1,1)),'p');
                %File_Filter = strcat('*.',lower(app.Touchstone_WR_Parameter),num2str(sz_Parameter_Matrix(1,1)),'p');
                File_Filter = strcat('*.',lower(app.Touchstone_WR_Parameter),num2str(app.Port_Num),'p'); %Two ports by default
                [app.Touchstone_Out_File_Name, app.Touchstone_Out_File_Path] = uiputfile(File_Filter,'Select the directory to save the file',app.Touchstone_Out_File_Name);

                if isequal(app.Touchstone_Out_File_Name,0) || isequal(app.Touchstone_Out_File_Path,0)
                   disp('User clicked Cancel.')
                   app.OutputfileEditField.Value = 'parameters'; %default value
                   app.Warning_Msg = msgbox("Save touchstone operation was cancelled","Warning");
                else
                   disp(['User selected ',fullfile(app.Touchstone_Out_File_Path,app.Touchstone_Out_File_Name),' and then clicked Save.'])
                   File_Name = split(app.Touchstone_Out_File_Name,'.');
                   app.OutputfileEditField.Value = File_Name{1};
                   app.Touchstone_Out_File = fullfile(app.Touchstone_Out_File_Path, app.Touchstone_Out_File_Name);
                   
                   % %TESTING
                   %  if contains(app.Touchstone_Out_File_Name,'.s2p') || contains(app.Touchstone_Out_File_Name,'.z2p') || contains(app.Touchstone_Out_File_Name,'.y2p') || contains(app.Touchstone_Out_File_Name,'.abcd2p')
                   %      app.Touchstone_Out_File_Name = split(app.Touchstone_Out_File_Name,'.');
                   %      app.Touchstone_Out_File_Name = app.Touchstone_Out_File_Name{1};
                   %  end
                   % Touchstone_Out_File_rfwrite = fullfile(app.Touchstone_Out_File_Path, strcat(app.Touchstone_Out_File_Name, '_rfwrite')); 
                   % %TESTING

                   switch app.Touchstone_WR_Parameter
                       case 'S'
                            Write_Touchstone(app, app.S_Parameters_Obj.Parameters, app.S_Parameters_Obj.Frequencies, app.Touchstone_Out_File, app.Touchstone_FreqUnit, app.S_Parameters_Obj.Impedance, app.Touchstone_Out_File_Format, app.Touchstone_WR_Parameter);
                            %rfwrite(app.S_Parameters_Obj.Parameters,app.S_Parameters_Obj.Frequencies,app.Touchstone_Out_File, 'FrequencyUnit', app.Touchstone_FreqUnit, 'ReferenceResistance', app.S_Parameters_Obj.Impedance, 'Format', app.Touchstone_Out_File_Format, 'ForceOverwrite', true);  
                       case 'Z'
                            Write_Touchstone(app, app.Z_Parameters_Obj.Parameters, app.Z_Parameters_Obj.Frequencies, app.Touchstone_Out_File, app.Touchstone_FreqUnit, app.Z0, app.Touchstone_Out_File_Format, app.Touchstone_WR_Parameter);
                            %TESTING (rfwrite & Write_Touchstone are equivalent - Yes)
                            %rfwrite(app.Z_Parameters_Obj.Parameters,app.Z_Parameters_Obj.Frequencies,strcat(Touchstone_Out_File_rfwrite, '.z2p'), 'FrequencyUnit', app.Touchstone_FreqUnit, 'ReferenceResistance', app.Z0, 'Format', app.Touchstone_Out_File_Format, 'ForceOverwrite', true,'Parameter', 'Z'); 
                       case 'Y'
                            Write_Touchstone(app, app.Y_Parameters_Obj.Parameters, app.Y_Parameters_Obj.Frequencies, app.Touchstone_Out_File, app.Touchstone_FreqUnit, app.Z0 , app.Touchstone_Out_File_Format, app.Touchstone_WR_Parameter);
                       case 'ABCD'
                            Write_Touchstone(app, app.ABCD_Parameters_Obj.Parameters, app.ABCD_Parameters_Obj.Frequencies, app.Touchstone_Out_File, app.Touchstone_FreqUnit, app.Z0 , app.Touchstone_Out_File_Format, app.Touchstone_WR_Parameter);
                   end 
                end
            else 
                app.Warning_Msg = msgbox("Please run a simulation before exporting file","Warning: No simulation was ran");
            end
        end

        % Value changed function: OutputfileEditField
        function OutputfileEditFieldValueChanged(app, event)
            app.Touchstone_Out_File_Name = app.OutputfileEditField.Value;
        end

        % Value changed function: ZoEditField
        function ZoEditFieldValueChanged(app, event)
            app.Zo = app.ZoEditField.Value;
        end

        % Value changed function: FormatDropDown
        function FormatDropDownValueChanged(app, event)
            app.Touchstone_Out_File_Format = app.FormatDropDown.Value;
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [app.Touchstone_Input_File_Name,app.Touchstone_Input_File_Path] = uigetfile({'*.s2p; *.z2p; *.y2p; *.abcd2p'}); %'*.*'
            if isequal(app.Touchstone_Input_File_Name,0) 
                disp('User selected Cancel');
            else
                if contains(app.Touchstone_Input_File_Name,'.s2p') || contains(app.Touchstone_Input_File_Name,'.z2p') || contains(app.Touchstone_Input_File_Name,'.y2p') || contains(app.Touchstone_Input_File_Name,'.abcd2p')
                    app.Touchstone_Input_File = fullfile(app.Touchstone_Input_File_Path,app.Touchstone_Input_File_Name);
                    disp(['User selected ', app.Touchstone_Input_File]);
                    
                    [RD_Parameter_Matrix, app.Frequencies, app.Zo, app.Touchstone_RD_Parameter] = Read_Touchstones(app, app.Touchstone_Input_File);
                
                    switch app.Touchstone_RD_Parameter %Convert read touchstone parameter to all parameters, overwrite parameters properties (global)
                        case 'S'
                            app.SMatrix = RD_Parameter_Matrix;
                            app.ZMatrix = s2z(app.SMatrix,app.Zo);
                            app.YMatrix = s2y(app.SMatrix,app.Zo);
                            app.ABCD_Matrix = s2abcd(app.SMatrix,app.Zo);
                        case 'Z'
                            app.ZMatrix = RD_Parameter_Matrix;
                            app.SMatrix = z2s(app.ZMatrix,app.Zo);
                            app.YMatrix = z2y(app.ZMatrix);
                            app.ABCD_Matrix = z2abcd(app.ZMatrix);
                        case 'Y'
                            app.YMatrix = RD_Parameter_Matrix;
                            app.SMatrix = y2s(app.YMatrix,app.Zo);
                            app.ZMatrix = y2z(app.YMatrix);
                            app.ABCD_Matrix = y2abcd(app.YMatrix);
                        case 'ABCD'
                            app.ABCD_Matrix = RD_Parameter_Matrix;
                            app.SMatrix = abcd2s(app.ABCD_Matrix,app.Zo);
                            app.ZMatrix = abcd2z(app.ABCD_Matrix);
                            app.YMatrix = abcd2y(app.ABCD_Matrix);
                    end
                    
                    %Create parameter objets for easy manipulation 
                    app.SMatrix_Touchstone = sparameters(app.SMatrix,app.Frequencies,app.Zo);
                    app.ZMatrix_Touchstone = zparameters(app.ZMatrix,app.Frequencies);
                    app.YMatrix_Touchstone = yparameters(app.YMatrix,app.Frequencies);
                    app.ABCD_Matrix_Touchstone = abcdparameters(app.ABCD_Matrix,app.Frequencies);
                    %RD_Params_Info = {app.SMatrix_Touchstone,app.ZMatrix_Touchstone,app.YMatrix_Touchstone,app.ABCD_Matrix_Touchstone};
                    RD_Params_Info = struct('S',app.SMatrix_Touchstone,'Z',app.ZMatrix_Touchstone,'Y',app.YMatrix_Touchstone,'ABCD',app.ABCD_Matrix_Touchstone);
                    
                    %For touchstone list box
                    app.InputfileEditField.Value = app.Touchstone_Input_File_Name;
                    app.ListBoxTouchstoneFiles.Items = [app.ListBoxTouchstoneFiles.Items, {app.Touchstone_Input_File_Name}]; %horizontal concatenation
                    app.ListBoxTouchstoneFiles.ItemsData = [app.ListBoxTouchstoneFiles.ItemsData, {RD_Params_Info}];
                    
                    %For CTC simulation list box %NOTE: CTC only operates with s parameters that are then converted to abcd.
                    if contains(app.Touchstone_Input_File_Name,'.s2p') || contains(app.Touchstone_Input_File_Name,'.z2p') || contains(app.Touchstone_Input_File_Name,'.y2p') || contains(app.Touchstone_Input_File_Name,'.abcd2p')
                        app.TouchstoneListBox.Items = [app.TouchstoneListBox.Items, {app.Touchstone_Input_File_Name}]; 
                        app.TouchstoneListBox.ItemsData = [app.TouchstoneListBox.ItemsData, {RD_Params_Info}];
                    end
                else
                    disp('Invalid file, must select a .s2p, .z2p, .y2p or .abcd2p file');
                    app.Warning_Msg = msgbox("Invalid file, must select a .s2p, .z2p, .y2p or .abcd2p file","Warning: Invalid input file");
                    app.Touchstone_Input_File = '';
                    app.InputfileEditField.items = app.Touchstone_Input_File;
                end
            end
        end

        % Menu selected function: netfileMenu
        function netfileMenuSelected(app, event)
            [app.net_fileName,app.net_filePath] = uigetfile('*.net'); %Files .cir could also be read
            if isequal(app.net_fileName,0)
                disp('User selected Cancel');
                app.Run_Valid = false;
            else
                disp(['User selected ', fullfile(app.net_filePath,app.net_fileName)]);
                app.net_file = fullfile(app.net_filePath,app.net_fileName);
                app.Netlist_CellArray = Netlist2CellArray(app, app.net_file); 
                app.Netlist_table = cell2table(app.Netlist_CellArray);
                app.Netlist_table.Properties.VariableNames = {'Name', '1st Node', '2nd Node', 'Type', 'Value','Bl','Operating Frequency'};
                app.UITable.Data = app.Netlist_table;
                app.ListBoxNetlistFiles.Items = [app.ListBoxNetlistFiles.Items, {app.net_fileName}]; %horizontal concatenation
                app.ListBoxNetlistFiles.ItemsData = [app.ListBoxNetlistFiles.ItemsData, {app.Netlist_CellArray}];
                app.ListBoxNetlistFiles.Value = app.Netlist_CellArray;

                %1st netlist to CTC simulation
                app.Netlist1ListBox.Items = [app.Netlist1ListBox.Items, {app.net_fileName}];
                app.Netlist1ListBox.ItemsData = [app.Netlist1ListBox.ItemsData, {app.Netlist_CellArray}];
                %app.CTC_Netlist1 = app.Netlist1ListBox.Value;
                
                %2st netlist to CTC simulation
                app.Netlist2ListBox.Items = [app.Netlist2ListBox.Items, {app.net_fileName}];
                app.Netlist2ListBox.ItemsData = [app.Netlist2ListBox.ItemsData, {app.Netlist_CellArray}];
                %app.CTC_Netlist2 = app.Netlist2ListBox.Value;

                app.Run_Valid = true;
            end  
        end

        % Value changed function: RectParameterPlotDropDown
        function RectParameterPlotDropDownValueChanged(app, event)
            app.RectPlotStyle = app.RectPlotStyleDropDown.Value;
            app.RectParameterPlot = app.RectParameterPlotDropDown.Value;

            if app.Parameters_Ready 
              switch app.RectParameterPlot
                case 'S'
                    Plot_Rect_Charts(app, app.S_Parameters_Obj, app.RectPlotStyle);
                case 'Z'
                    Plot_Rect_Charts(app, app.Z_Parameters_Obj, app.RectPlotStyle);
                case 'Y'
                    Plot_Rect_Charts(app, app.Y_Parameters_Obj, app.RectPlotStyle);
                case 'ABCD'
                    Plot_Rect_Charts(app, app.ABCD_Parameters_Obj, app.RectPlotStyle);
              end 
            end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            %Retrieve simulation arguments
            app.Parameters_Ready = false;
            app.CTC_Netlist1 = app.Netlist1ListBox.Value;
            app.CTC_Netlist2  = app.Netlist2ListBox.Value;
            app.CTC_Touchstone = app.TouchstoneListBox.Value;
            app.RectParameterPlot = app.RectParameterPlotDropDown.Value;
            app.RectPlotStyle = app.RectPlotStyleDropDown.Value;
            app.PolarParameterPlot = app.PolarParameterPlotDropDown.Value;
            app.SmithParameterPlot = app.SmithParameterPlotDropDown.Value;
                 
            if isempty(app.CTC_Touchstone)
                app.Warning_Msg = msgbox("Please load and select a Touchstone file from CTC tab","Warning: No simulation was ran");
            else
                progDlg = uiprogressdlg(app.UIFigure,'Title','Simulator info', 'Message','Start of simulation');
                pause(.5)
    
                % Perform calculations
                progDlg.Value = .33; 
                progDlg.Message = 'Loading data';
                pause(.5)
    
                % Perform calculations
                progDlg.Value = .67;
                progDlg.Message = 'Calculating circuit parameters';
                pause(.5)

                %Circuit_Touchstone_Connection 
                [CTC_Parameters_Struct, app.Frequencies, app.Zo, app.Port_Num] = Circuit_Touchstone_Connection(app, app.CTC_Netlist1, app.CTC_Touchstone.S, app.CTC_Netlist2);
                app.Z0 = app.Zo; %Since Zo can be edited by user, Z0 keeps the value used during simulation
                
                %Update app parameters properties (global) accoding sim results
                app.S_Parameters_Obj = CTC_Parameters_Struct.S;
                app.Z_Parameters_Obj = CTC_Parameters_Struct.Z;
                app.Y_Parameters_Obj = CTC_Parameters_Struct.Y;
                app.ABCD_Parameters_Obj = CTC_Parameters_Struct.ABCD;

                %Updating fields according to CTC simulation
                app.StartFreqEditField.Value = app.Frequencies(1,1);
                app.DropDownStartFreq.Value = 'Hz'; 
                app.StepsEditField.Value = length(app.Frequencies);
                app.EndFreqEditField.Value = app.Frequencies(app.StepsEditField.Value,1);
                app.DropDownEndFreq.Value = 'Hz';
                app.PortnumberEditField.Value = app.Port_Num;
                app.ZoEditField.Value = app.Zo;
    
                %Plot results
                % app.RectParameterPlot = app.RectParameterPlotDropDown.Value;
                % app.PolarParameterPlot = app.PolarParameterPlotDropDown.Value;
                % app.SmithParameterPlot = app.SmithParameterPlotDropDown.Value;

                switch app.RectParameterPlot 
                    case 'S'
                        Plot_Rect_Charts(app, app.S_Parameters_Obj, app.RectPlotStyle);
                    case 'Z'
                        Plot_Rect_Charts(app, app.Z_Parameters_Obj, app.RectPlotStyle);
                    case 'Y'
                        Plot_Rect_Charts(app, app.Y_Parameters_Obj, app.RectPlotStyle);
                    case 'ABCD'
                        Plot_Rect_Charts(app, app.ABCD_Parameters_Obj, app.RectPlotStyle);
                end 

                switch app.PolarParameterPlot 
                    case 'S'
                        Plot_Polar_Charts(app, app.S_Parameters_Obj);
                    case 'Z'
                        Plot_Polar_Charts(app, app.Z_Parameters_Obj);
                    case 'Y'
                        Plot_Polar_Charts(app, app.Y_Parameters_Obj);
                    case 'ABCD'
                        Plot_Polar_Charts(app, app.ABCD_Parameters_Obj);
                end 

                switch app.SmithParameterPlot 
                    case 'S'
                        Plot_Smith_Charts(app, app.S_Parameters_Obj);
                    case 'Z'
                        Plot_Smith_Charts(app, app.Z_Parameters_Obj);
                    case 'Y'
                        Plot_Smith_Charts(app, app.Y_Parameters_Obj);
                    case 'ABCD'
                        Plot_Smith_Charts(app, app.ABCD_Parameters_Obj);
                end 

                app.Parameters_Ready = true; %For touchstone file storage

                % Finish calculations
                progDlg.Value = 1;
                progDlg.Message = 'Simulation complete';
                pause(.5)
            
                % Close dialog box
                close(progDlg) 
            end
        end

        % Value changed function: Netlist1ListBox
        function Netlist1ListBoxValueChanged(app, event)
            app.CTC_Netlist1 = app.Netlist1ListBox.Value;
        end

        % Value changed function: Netlist2ListBox
        function Netlist2ListBoxValueChanged(app, event)
            app.CTC_Netlist2  = app.Netlist2ListBox.Value;
        end

        % Value changed function: ListBoxNetlistFiles
        function ListBoxNetlistFilesValueChanged(app, event)
            app.Netlist_CellArray = app.ListBoxNetlistFiles.Value;
            app.Netlist_table = cell2table(app.Netlist_CellArray);
            app.Netlist_table.Properties.VariableNames = {'Name', '1st Node', '2nd Node', 'Type', 'Value','Bl','Operating Frequency'};
            app.UITable.Data = app.Netlist_table;
            app.Run_Valid = true;
        end

        % Value changed function: TouchstoneListBox
        function TouchstoneListBoxValueChanged(app, event)
            app.CTC_Touchstone = app.TouchstoneListBox.Value;
        end

        % Value changed function: ListBoxTouchstoneFiles
        function ListBoxTouchstoneFilesValueChanged(app, event)
            app.Sel_Touchstone = app.ListBoxTouchstoneFiles.Value; %Only displays the loaded touchtone files
        end

        % Value changed function: FreqUnitDropDown
        function FreqUnitDropDownValueChanged(app, event)
            app.Touchstone_FreqUnit = app.FreqUnitDropDown.Value;
        end

        % Menu selected function: ClearTourchstonefilesMenu
        function ClearTourchstonefilesMenuSelected(app, event)
            app.ListBoxTouchstoneFiles.Items = {};
            app.ListBoxTouchstoneFiles.ItemsData = {};
            app.TouchstoneListBox.Items = {};
            app.TouchstoneListBox.ItemsData = {};
            app.InputfileEditField.Value = '';
            app.OutputfileEditField.Value = 'parameters';
        end

        % Menu selected function: ClearNetlistfilesMenu
        function ClearNetlistfilesMenuSelected(app, event)
            app.ListBoxNetlistFiles.Items = {};  
            app.ListBoxNetlistFiles.ItemsData = {}; 
            app.Netlist1ListBox.Items = {'None'};
            app.Netlist1ListBox.ItemsData = {'None'};
            app.Netlist2ListBox.Items = {'None'};
            app.Netlist2ListBox.ItemsData = {'None'};
            %clear netlist table
            varTypes = ["string","string","string", "string","string", "string","string"];
            varNames = {'Name', '1st Node', '2nd Node', 'Type', 'Value','Bl','Operating Frequency'};
            app.Netlist_table = table('Size',[1 7],'VariableTypes',varTypes,'VariableNames',varNames); %[row col]
            app.Netlist_table{:,:} = zeros(1,7);
            app.UITable.Data = app.Netlist_table;
        end

        % Menu selected function: ClearAllMenu
        function ClearAllMenuSelected(app, event)
            Reset_Values_Fun(app);
        end

        % Value changed function: RectPlotStyleDropDown
        function RectPlotStyleDropDownValueChanged(app, event)
            app.RectPlotStyle = app.RectPlotStyleDropDown.Value;
            app.RectParameterPlot = app.RectParameterPlotDropDown.Value;

            if app.Parameters_Ready 
              switch app.RectParameterPlot
                case 'S'
                    Plot_Rect_Charts(app, app.S_Parameters_Obj, app.RectPlotStyle);
                case 'Z'
                    Plot_Rect_Charts(app, app.Z_Parameters_Obj, app.RectPlotStyle);
                case 'Y'
                    Plot_Rect_Charts(app, app.Y_Parameters_Obj, app.RectPlotStyle);
                case 'ABCD'
                    Plot_Rect_Charts(app, app.ABCD_Parameters_Obj, app.RectPlotStyle);
              end 
            end
        end

        % Value changed function: ParameterDropDown
        function ParameterDropDownValueChanged(app, event)
            app.Touchstone_WR_Parameter = app.ParameterDropDown.Value;
        end

        % Value changed function: PolarParameterPlotDropDown
        function PolarParameterPlotDropDownValueChanged(app, event)
            app.PolarParameterPlot = app.PolarParameterPlotDropDown.Value;
            
            if app.Parameters_Ready 
              switch app.PolarParameterPlot
                case 'S'
                    Plot_Polar_Charts(app, app.S_Parameters_Obj);
                case 'Z'
                    Plot_Polar_Charts(app, app.Z_Parameters_Obj);
                case 'Y'
                    Plot_Polar_Charts(app, app.Y_Parameters_Obj);
                case 'ABCD'
                    Plot_Polar_Charts(app, app.ABCD_Parameters_Obj);
              end 
            end
        end

        % Value changed function: SmithParameterPlotDropDown
        function SmithParameterPlotDropDownValueChanged(app, event)
            app.SmithParameterPlot = app.SmithParameterPlotDropDown.Value;
            
            if app.Parameters_Ready 
              switch app.SmithParameterPlot
                case 'S'
                    Plot_Smith_Charts(app, app.S_Parameters_Obj);
                case 'Z'
                    Plot_Smith_Charts(app, app.Z_Parameters_Obj);
                case 'Y'
                    Plot_Smith_Charts(app, app.Y_Parameters_Obj);
                case 'ABCD'
                    Plot_Smith_Charts(app, app.ABCD_Parameters_Obj);
              end 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NetlistMenu = uimenu(app.UIFigure);
            app.NetlistMenu.Text = 'Netlist';
            app.OpenMenu = uimenu(app.NetlistMenu);
            app.OpenMenu.Text = 'Open';
            app.netfileMenu = uimenu(app.OpenMenu);
            app.netfileMenu.MenuSelectedFcn = createCallbackFcn(app, @netfileMenuSelected, true);
            app.netfileMenu.Text = '.net file';
            app.xlsxfileMenu = uimenu(app.OpenMenu);
            app.xlsxfileMenu.MenuSelectedFcn = createCallbackFcn(app, @xlsxfileMenuSelected, true);
            app.xlsxfileMenu.Text = '.xlsx file';
            app.ClearMenu = uimenu(app.UIFigure);
            app.ClearMenu.Text = 'Clear';
            app.ClearAllMenu = uimenu(app.ClearMenu);
            app.ClearAllMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearAllMenuSelected, true);
            app.ClearAllMenu.Text = 'Clear All';
            app.ClearTourchstonefilesMenu = uimenu(app.ClearMenu);
            app.ClearTourchstonefilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearTourchstonefilesMenuSelected, true);
            app.ClearTourchstonefilesMenu.Text = 'Clear Tourchstone files';
            app.ClearNetlistfilesMenu = uimenu(app.ClearMenu);
            app.ClearNetlistfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearNetlistfilesMenuSelected, true);
            app.ClearNetlistfilesMenu.Text = 'Clear Netlist files';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ControlPanel = uipanel(app.GridLayout);
            app.ControlPanel.Title = 'Control Panel';
            app.StartFreqEditFieldLabel = uilabel(app.ControlPanel);
            app.StartFreqEditFieldLabel.Text = 'Start Freq';
            app.StartFreqEditField = uieditfield(app.ControlPanel, 'numeric');
            app.StartFreqEditField.Limits = [1 Inf];
            app.StartFreqEditField.ValueChangedFcn = createCallbackFcn(app, @StartFreqEditFieldValueChanged, true);
            app.StartFreqEditField.Value = 1;
            app.EndFreqEditFieldLabel = uilabel(app.ControlPanel);
            app.EndFreqEditFieldLabel.Text = 'End Freq';
            app.EndFreqEditField = uieditfield(app.ControlPanel, 'numeric');
            app.EndFreqEditField.Limits = [1 Inf];
            app.EndFreqEditField.ValueChangedFcn = createCallbackFcn(app, @EndFreqEditFieldValueChanged, true);
            app.EndFreqEditField.Value = 1;
            app.StepsEditFieldLabel = uilabel(app.ControlPanel);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.StepsEditField = uieditfield(app.ControlPanel, 'numeric');
            app.StepsEditField.Limits = [1 Inf];
            app.StepsEditField.ValueChangedFcn = createCallbackFcn(app, @StepsEditFieldValueChanged, true);
            app.StepsEditField.Value = 1;
            app.DropDownStartFreq = uidropdown(app.ControlPanel);
            app.DropDownStartFreq.Items = {'Hz', 'KHz', 'MHz', 'GHz', 'THz'};
            app.DropDownStartFreq.ValueChangedFcn = createCallbackFcn(app, @DropDownStartFreqValueChanged, true);
            app.DropDownStartFreq.Value = 'Hz';
            app.DropDownEndFreq = uidropdown(app.ControlPanel);
            app.DropDownEndFreq.Items = {'Hz', 'KHz', 'MHz', 'GHz', 'THz'};
            app.DropDownEndFreq.ValueChangedFcn = createCallbackFcn(app, @DropDownEndFreqValueChanged, true);
            app.DropDownEndFreq.Value = 'Hz';
            app.RunButton = uibutton(app.ControlPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TouchtoneFileoptionsPanel = uipanel(app.ControlPanel);
            app.TouchtoneFileoptionsPanel.Title = 'Touchtone File options';
            app.OpenButton = uibutton(app.TouchtoneFileoptionsPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Icon = fullfile(pathToMLAPP, 'folderIcon.png');
            app.OpenButton.Text = '';
            app.SaveButton = uibutton(app.TouchtoneFileoptionsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.InputfileEditFieldLabel = uilabel(app.TouchtoneFileoptionsPanel);
            app.InputfileEditFieldLabel.Text = 'Input file';
            app.InputfileEditField = uieditfield(app.TouchtoneFileoptionsPanel, 'text');
            app.InputfileEditField.Editable = 'off';
            app.OutputfileEditFieldLabel = uilabel(app.TouchtoneFileoptionsPanel);
            app.OutputfileEditFieldLabel.Text = 'Output file';
            app.OutputfileEditField = uieditfield(app.TouchtoneFileoptionsPanel, 'text');
            app.OutputfileEditField.ValueChangedFcn = createCallbackFcn(app, @OutputfileEditFieldValueChanged, true);
            app.OutputfileEditField.Value = 'parameters';
            app.FreqUnitDropDownLabel = uilabel(app.TouchtoneFileoptionsPanel);
            app.FreqUnitDropDownLabel.Text = 'Freq Unit';
            app.FreqUnitDropDown = uidropdown(app.TouchtoneFileoptionsPanel);
            app.FreqUnitDropDown.Items = {'Hz', 'KHz', 'MHz', 'GHz'};
            app.FreqUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @FreqUnitDropDownValueChanged, true);
            app.FreqUnitDropDown.Value = 'Hz';
            app.ParameterDropDownLabel = uilabel(app.TouchtoneFileoptionsPanel);
            app.ParameterDropDownLabel.Text = 'Parameter';
            app.ParameterDropDown = uidropdown(app.TouchtoneFileoptionsPanel);
            app.ParameterDropDown.Items = {'S', 'Z', 'Y', 'ABCD'};
            app.ParameterDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterDropDownValueChanged, true);
            app.ParameterDropDown.Value = 'S';
            app.FormatDropDownLabel = uilabel(app.TouchtoneFileoptionsPanel);
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.TouchtoneFileoptionsPanel);
            app.FormatDropDown.Items = {'RI', 'MA', 'DB'};
            app.FormatDropDown.ValueChangedFcn = createCallbackFcn(app, @FormatDropDownValueChanged, true);
            app.FormatDropDown.Value = 'RI';
            app.PortnumberEditFieldLabel = uilabel(app.ControlPanel);
            app.PortnumberEditFieldLabel.Text = 'Port number';
            app.PortnumberEditField = uieditfield(app.ControlPanel, 'numeric');
            app.PortnumberEditField.Limits = [1 Inf];
            app.PortnumberEditField.RoundFractionalValues = 'on';
            app.PortnumberEditField.ValueChangedFcn = createCallbackFcn(app, @PortnumberEditFieldValueChanged, true);
            app.PortnumberEditField.Editable = 'off';
            app.PortnumberEditField.Value = 2;
            app.ZoEditFieldLabel = uilabel(app.ControlPanel);
            app.ZoEditFieldLabel.Text = 'Zo';
            app.ZoEditField = uieditfield(app.ControlPanel, 'numeric');
            app.ZoEditField.Limits = [1 Inf];
            app.ZoEditField.ValueChangedFcn = createCallbackFcn(app, @ZoEditFieldValueChanged, true);
            app.ZoEditField.Value = 50;
            app.ZoEditFieldLabel_2 = uilabel(app.ControlPanel);
            app.ZoEditFieldLabel_2.Text = 'Ω';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Name'; '1st Node'; '2nd Node'; 'Type'; 'Value'};
            app.UITable.RowName = {};
            app.TouchstoneFilesLabel = uilabel(app.GridLayout3);
            app.TouchstoneFilesLabel.Text = 'Touchstone Files';
            app.ListBoxTouchstoneFiles = uilistbox(app.GridLayout3);
            app.ListBoxTouchstoneFiles.Items = {};
            app.ListBoxTouchstoneFiles.ValueChangedFcn = createCallbackFcn(app, @ListBoxTouchstoneFilesValueChanged, true);
            app.ListBoxTouchstoneFiles.Value = {};
            app.NetlistFilesLabel = uilabel(app.GridLayout3);
            app.NetlistFilesLabel.Text = 'Netlist Files';
            app.ListBoxNetlistFiles = uilistbox(app.GridLayout3);
            app.ListBoxNetlistFiles.Items = {};
            app.ListBoxNetlistFiles.ValueChangedFcn = createCallbackFcn(app, @ListBoxNetlistFilesValueChanged, true);
            app.ListBoxNetlistFiles.Value = {};
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.RectangularTab = uitab(app.TabGroup);
            app.RectangularTab.Title = 'Rectangular';
            app.GridLayout4 = uigridlayout(app.RectangularTab);
            app.UIAxesP11 = uiaxes(app.GridLayout4);
            xlabel(app.UIAxesP11, 'Freq')
            ylabel(app.UIAxesP11, 'dB')
            zlabel(app.UIAxesP11, 'Z')
            app.UIAxesP22 = uiaxes(app.GridLayout4);
            xlabel(app.UIAxesP22, 'Freq')
            ylabel(app.UIAxesP22, 'dB')
            zlabel(app.UIAxesP22, 'Z')
            app.UIAxesP21 = uiaxes(app.GridLayout4);
            xlabel(app.UIAxesP21, 'Freq')
            ylabel(app.UIAxesP21, 'dB')
            zlabel(app.UIAxesP21, 'Z')
            app.UIAxesP12 = uiaxes(app.GridLayout4);
            xlabel(app.UIAxesP12, 'Freq')
            ylabel(app.UIAxesP12, 'dB')
            zlabel(app.UIAxesP12, 'Z')
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.ParameterDropDown_2Label = uilabel(app.GridLayout5);
            app.ParameterDropDown_2Label.Text = 'Parameter';
            app.RectParameterPlotDropDown = uidropdown(app.GridLayout5);
            app.RectParameterPlotDropDown.Items = {'S', 'Z', 'Y', 'ABCD'};
            app.RectParameterPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @RectParameterPlotDropDownValueChanged, true);
            app.RectParameterPlotDropDown.Value = 'S';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.PlotStyleDropDownLabel = uilabel(app.GridLayout6);
            app.PlotStyleDropDownLabel.Text = 'Plot Style';
            app.RectPlotStyleDropDown = uidropdown(app.GridLayout6);
            app.RectPlotStyleDropDown.Items = {'Magnitude', 'Phase', 'Real', 'Imaginary'};
            app.RectPlotStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @RectPlotStyleDropDownValueChanged, true);
            app.RectPlotStyleDropDown.Value = 'Magnitude';
            app.PolarTab = uitab(app.TabGroup);
            app.PolarTab.Title = 'Polar';
            app.GridLayout7 = uigridlayout(app.PolarTab);
            app.GridLayout5_3 = uigridlayout(app.GridLayout7);
            app.ParameterDropDown_2Label_2 = uilabel(app.GridLayout5_3);
            app.ParameterDropDown_2Label_2.Text = 'Parameter';
            app.PolarParameterPlotDropDown = uidropdown(app.GridLayout5_3);
            app.PolarParameterPlotDropDown.Items = {'S', 'Z', 'Y', 'ABCD'};
            app.PolarParameterPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @PolarParameterPlotDropDownValueChanged, true);
            app.PolarParameterPlotDropDown.Value = 'S';
            app.SmithChartTab = uitab(app.TabGroup);
            app.SmithChartTab.Title = 'Smith Chart';
            app.GridLayout8 = uigridlayout(app.SmithChartTab);
            app.GridLayout5_4 = uigridlayout(app.GridLayout8);
            app.ParameterDropDown_3Label = uilabel(app.GridLayout5_4);
            app.ParameterDropDown_3Label.Text = 'Parameter';
            app.SmithParameterPlotDropDown = uidropdown(app.GridLayout5_4);
            app.SmithParameterPlotDropDown.Items = {'S', 'Z', 'Y', 'ABCD'};
            app.SmithParameterPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @SmithParameterPlotDropDownValueChanged, true);
            app.SmithParameterPlotDropDown.Value = 'S';
            app.P11SmithChartPanel = uipanel(app.GridLayout8);
            app.P12SmithChartPanel = uipanel(app.GridLayout8);
            app.P21SmithChartPanel = uipanel(app.GridLayout8);
            app.P22SmithChartPanel = uipanel(app.GridLayout8);
            app.CTCTab = uitab(app.TabGroup);
            app.CTCTab.Title = 'CTC';
            app.GridLayout9 = uigridlayout(app.CTCTab);
            app.Image = uiimage(app.GridLayout9);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ADS touchstone sim.png');
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.SimulateButton = uibutton(app.GridLayout10, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.Netlist1ListBoxLabel = uilabel(app.GridLayout10);
            app.Netlist1ListBoxLabel.Text = 'Netlist1';
            app.Netlist1ListBox = uilistbox(app.GridLayout10);
            app.Netlist1ListBox.Items = {'None'};
            app.Netlist1ListBox.ItemsData = {'None'};
            app.Netlist1ListBox.ValueChangedFcn = createCallbackFcn(app, @Netlist1ListBoxValueChanged, true);
            app.Netlist1ListBox.Value = 'None';
            app.Netlist2ListBoxLabel = uilabel(app.GridLayout10);
            app.Netlist2ListBoxLabel.Text = 'Netlist2';
            app.Netlist2ListBox = uilistbox(app.GridLayout10);
            app.Netlist2ListBox.Items = {'None'};
            app.Netlist2ListBox.ItemsData = {'None'};
            app.Netlist2ListBox.ValueChangedFcn = createCallbackFcn(app, @Netlist2ListBoxValueChanged, true);
            app.Netlist2ListBox.Value = 'None';
            app.TouchstoneListBoxLabel = uilabel(app.GridLayout10);
            app.TouchstoneListBoxLabel.Text = 'Touchstone';
            app.TouchstoneListBox = uilistbox(app.GridLayout10);
            app.TouchstoneListBox.Items = {};
            app.TouchstoneListBox.ValueChangedFcn = createCallbackFcn(app, @TouchstoneListBoxValueChanged, true);
            app.TouchstoneListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end